classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        ForwardTab                 matlab.ui.container.Tab
        Twist6                     matlab.ui.control.Label
        Twist5                     matlab.ui.control.Label
        Twist4                     matlab.ui.control.Label
        Twist3                     matlab.ui.control.Label
        Twist2                     matlab.ui.control.Label
        Twist1                     matlab.ui.control.Label
        offset6                    matlab.ui.control.Label
        offset5                    matlab.ui.control.Label
        offset4                    matlab.ui.control.Label
        offset3                    matlab.ui.control.Label
        offset2                    matlab.ui.control.Label
        offset                     matlab.ui.control.Label
        Tha6                       matlab.ui.control.Label
        Tha5                       matlab.ui.control.Label
        Tha4                       matlab.ui.control.Label
        Tha3                       matlab.ui.control.Label
        Tha2                       matlab.ui.control.Label
        Tha1                       matlab.ui.control.Label
        length6                    matlab.ui.control.Label
        length5                    matlab.ui.control.Label
        length4                    matlab.ui.control.Label
        length3                    matlab.ui.control.Label
        length2                    matlab.ui.control.Label
        length1                    matlab.ui.control.Label
        Z_2                        matlab.ui.control.NumericEditField
        ZEditFieldLabel_2          matlab.ui.control.Label
        Y_2                        matlab.ui.control.NumericEditField
        YEditFieldLabel_2          matlab.ui.control.Label
        X_2                        matlab.ui.control.NumericEditField
        XEditFieldLabel_2          matlab.ui.control.Label
        Twist_1                    matlab.ui.control.NumericEditField
        offset_1                   matlab.ui.control.NumericEditField
        Tha_1                      matlab.ui.control.NumericEditField
        length_1                   matlab.ui.control.NumericEditField
        ForwardButton              matlab.ui.control.Button
        DOFDropDown                matlab.ui.control.DropDown
        DOFDropDownLabel           matlab.ui.control.Label
        Twist_6                    matlab.ui.control.NumericEditField
        offset_6                   matlab.ui.control.NumericEditField
        Tha_6                      matlab.ui.control.NumericEditField
        length_6                   matlab.ui.control.NumericEditField
        Twist_5                    matlab.ui.control.NumericEditField
        offset_5                   matlab.ui.control.NumericEditField
        Tha_5                      matlab.ui.control.NumericEditField
        length_5                   matlab.ui.control.NumericEditField
        Twist_4                    matlab.ui.control.NumericEditField
        offset_4                   matlab.ui.control.NumericEditField
        Tha_4                      matlab.ui.control.NumericEditField
        length_4                   matlab.ui.control.NumericEditField
        Twist_3                    matlab.ui.control.NumericEditField
        offset_3                   matlab.ui.control.NumericEditField
        Tha_3                      matlab.ui.control.NumericEditField
        length_3                   matlab.ui.control.NumericEditField
        Twist_2                    matlab.ui.control.NumericEditField
        offset_2                   matlab.ui.control.NumericEditField
        Tha_2                      matlab.ui.control.NumericEditField
        length_2                   matlab.ui.control.NumericEditField
        TwoLinkTab                 matlab.ui.container.Tab
        EnterYourLinkLimitsLabel   matlab.ui.control.Label
        peterz                     matlab.ui.control.Label
        petery                     matlab.ui.control.Label
        peterx                     matlab.ui.control.Label
        peter_Tha_2                matlab.ui.control.Label
        peter_Tha_1                matlab.ui.control.Label
        nd_Solution_Th_2           matlab.ui.control.Label
        nd_Solution_Th_1           matlab.ui.control.Label
        nd_Solution_Th1            matlab.ui.control.NumericEditField
        nd_Solution_Th2            matlab.ui.control.NumericEditField
        WorkModeDropDown           matlab.ui.control.DropDown
        WorkModeDropDownLabel      matlab.ui.control.Label
        Link2offset                matlab.ui.control.NumericEditField
        Link1offsetLabel           matlab.ui.control.Label
        Link1offset                matlab.ui.control.NumericEditField
        Link2offsetLabel           matlab.ui.control.Label
        peter_Tha1                 matlab.ui.control.NumericEditField
        peter_Tha2                 matlab.ui.control.NumericEditField
        peter_x                    matlab.ui.control.NumericEditField
        peter_y                    matlab.ui.control.NumericEditField
        peter_z                    matlab.ui.control.NumericEditField
        Z                          matlab.ui.control.NumericEditField
        ZEditFieldLabel_3          matlab.ui.control.Label
        Y                          matlab.ui.control.NumericEditField
        YEditFieldLabel_3          matlab.ui.control.Label
        X                          matlab.ui.control.NumericEditField
        XEditFieldLabel_3          matlab.ui.control.Label
        Alpha2                     matlab.ui.control.NumericEditField
        Alpha2EditFieldLabel       matlab.ui.control.Label
        Alpha1                     matlab.ui.control.NumericEditField
        Alpha1EditFieldLabel       matlab.ui.control.Label
        Theta2                     matlab.ui.control.NumericEditField
        Theta2EditFieldLabel       matlab.ui.control.Label
        Theta1                     matlab.ui.control.NumericEditField
        Theta1EditFieldLabel       matlab.ui.control.Label
        SelectModeDropDown         matlab.ui.control.DropDown
        SelectModeDropDownLabel    matlab.ui.control.Label
        Link2length                matlab.ui.control.NumericEditField
        Link2lengthEditFieldLabel  matlab.ui.control.Label
        Link1length                matlab.ui.control.NumericEditField
        Link1lengthEditFieldLabel  matlab.ui.control.Label
        SubmitButton               matlab.ui.control.Button
        text                       matlab.ui.control.EditField
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            app.X_2.Enable = true;
            app.Y_2.Enable = true;
            app.Z_2.Enable = true;
            
            alpha = sym('alpha');
            theta = sym('theta');
            a = sym('a');
            d = sym('d');
            
            % DH parameters the user should Enter
            a1= app.length_1.Value ;
            d1= app.offset_1.Value ;
            theta1= app.Tha_1.Value*pi/180;
            alpha1= app.Twist_1.Value*pi/180;
           
            a2= app.length_2.Value ;
            d2= app.offset_2.Value ;
            theta2= app.Tha_2.Value*pi/180;
            alpha2= app.Twist_2.Value*pi/180;
            
            a3= app.length_3.Value ;
            d3= app.offset_3.Value ;
            theta3= app.Tha_3.Value*pi/180;
            alpha3= app.Twist_3.Value*pi/180;
           
            a4= app.length_4.Value ;
            d4= app.offset_4.Value ;
            theta4= app.Tha_4.Value*pi/180;
            alpha4= app.Twist_4.Value*pi/180;
       
            a5= app.length_5.Value ;
            d5= app.offset_5.Value ;
            theta5= app.Tha_5.Value*pi/180;
            alpha5= app.Twist_5.Value*pi/180;
            
            a6= app.length_6.Value ;
            d6= app.offset_6.Value ;
            theta6= app.Tha_6.Value*pi/180;
            alpha6= app.Twist_6.Value*pi/180;
        
            if (strcmp(app.DOFDropDown.Value,'2'))
                 N = 2;
                DhTable = [a1,alpha1,d1,theta1;
                           a2,alpha2,d2,theta2;];
                
                L(1) = Link([ theta1, d1, a1,alpha1]);
                L(2) = Link([ theta2, d2, a2,alpha2]);
               
                q = [theta1 theta2]; 
            elseif(strcmp(app.DOFDropDown.Value,'3'))
                 N = 3;
                 DhTable = [a1,alpha1,d1,theta1;
                           a2,alpha2,d2,theta2;
                           a3,alpha3,d3,theta3;];
                 
                 L(1) = Link([ theta1, d1, a1,alpha1]);
                 L(2) = Link([ theta2, d2, a2,alpha2]);
                 L(3) = Link([ theta3, d3, a3,alpha3]);

                 q = [theta1 theta2 theta3]; 
            elseif(strcmp(app.DOFDropDown.Value,'4'))    
                 N = 4;
                 DhTable = [a1,alpha1,d1,theta1;
                            a2,alpha2,d2,theta2;
                            a3,alpha3,d3,theta3;
                            a4,alpha4,d4,theta4;];
                
                 L(1) = Link([ theta1, d1, a1,alpha1]);
                 L(2) = Link([ theta2, d2, a2,alpha2]);
                 L(3) = Link([ theta3, d3, a3,alpha3]);
                 L(4) = Link([ theta4, d4, a4,alpha4]);

                 q = [theta1 theta2 theta3 theta4]; 
            elseif(strcmp(app.DOFDropDown.Value,'5'))    
                N = 5;
                DhTable = [a1,alpha1,d1,theta1;
                           a2,alpha2,d2,theta2;
                           a3,alpha3,d3,theta3;
                           a4,alpha4,d4,theta4;
                           a5,alpha5,d5,theta5;];
                            
                L(1) = Link([ theta1, d1, a1,alpha1]);
                L(2) = Link([ theta2, d2, a2,alpha2]);
                L(3) = Link([ theta3, d3, a3,alpha3]);
                L(4) = Link([ theta4, d4, a4,alpha4]);
                L(5) = Link([ theta5, d5, a5,alpha5]);

                q = [theta1 theta2 theta3 theta4 theta5];  
            elseif(strcmp(app.DOFDropDown.Value,'6'))    
                 N = 6;
                 DhTable = [a1,alpha1,d1,theta1;
                           a2,alpha2,d2,theta2;
                           a3,alpha3,d3,theta3;
                           a4,alpha4,d4,theta4;
                           a5,alpha5,d5,theta5;
                           a6,alpha6,d6,theta6;];
                
                 L(1) = Link([ theta1, d1, a1,alpha1]);
                 L(2) = Link([ theta2, d2, a2,alpha2]);
                 L(3) = Link([ theta3, d3, a3,alpha3]);
                 L(4) = Link([ theta4, d4, a4,alpha4]);
                 L(5) = Link([ theta5, d5, a5,alpha5]);
                 L(6) = Link([ theta6, d6, a6,alpha6]);
                   
                 q = [theta1 theta2 theta3 theta4 theta5 theta6]; 
            end
            
            % calculating DH matrcies and maltiplying them
            % A(1)*A(2)*..........
            A = cell(1,N);
            for i = 1:N
                a = DhTable(i,1);
                alpha = DhTable(i,2);
                d = DhTable(i,3);
                theta = DhTable(i,4);
                A{i} = [ cos(theta)          -sin(theta)*cos(alpha)      sin(theta)*sin(alpha)         a*cos(theta);
                         sin(theta)           cos(theta)*cos(alpha)     -cos(theta)*sin(alpha)         a*sin(theta);
                             0                    sin(alpha)                 cos(alpha)                    d;
                             0                    0                          0                             1;];      
            end
            
            T = eye(4);
            for i=1:N
                T = T*A{i}; 
            end
            
            x = T(1, 4);
            y = T(2, 4);
            z = T(3, 4);
            app.X_2.Value = x;
            app.Y_2.Value = y;
            app.Z_2.Value = z;
            
            % confirming by Peter Corke toolbox                
            Robot = SerialLink(L);
            Robot.name = 'Robot';
            Robot.plot(q);  
            
        end

        % Value changed function: DOFDropDown
        function DOFDropDownValueChanged(app, event)
            
            if (strcmp(app.DOFDropDown.Value,'2'))
                % make the DH parameters Edit Field VISIBLE
                app.length1.Visible = true;
                app.length_1.Visible = true;
                app.offset_1.Visible = true;
                app.Tha_1.Visible = true;
                app.Twist_1.Visible = true;
                    
                app.length_2.Visible = true;
                app.offset_2.Visible = true;
                app.Tha_2.Visible = true;
                app.Twist_2.Visible = true;
                    
                app.length_3.Visible = false;
                app.offset_3.Visible = false;
                app.Tha_3.Visible = false;
                app.Twist_3.Visible = false;
                           
                app.length_4.Visible = false;
                app.offset_4.Visible = false;
                app.Tha_4.Visible = false;
                app.Twist_4.Visible = false;            
                
                app.length_5.Visible = false;
                app.offset_5.Visible = false;
                app.Tha_5.Visible = false;
                app.Twist_5.Visible = false;   
            
                app.length_6.Visible = false;
                app.offset_6.Visible = false;
                app.Tha_6.Visible = false;
                app.Twist_6.Visible = false;
          
                % make the DH parameters Lables VISIBLE               
                app.length1.Visible = true;
                app.offset.Visible = true;
                app.Tha1.Visible = true;
                app.Twist1.Visible = true;
                    
                app.length2.Visible = true;
                app.offset2.Visible = true;
                app.Tha2.Visible = true;
                app.Twist2.Visible = true;
                    
                app.length3.Visible = false;
                app.offset3.Visible = false;
                app.Tha3.Visible = false;
                app.Twist3.Visible = false;
                           
                app.length4.Visible = false;
                app.offset4.Visible = false;
                app.Tha4.Visible = false;
                app.Twist4.Visible = false;            
                
                app.length5.Visible = false;
                app.offset5.Visible = false;
                app.Tha5.Visible = false;
                app.Twist5.Visible = false;   
            
                app.length6.Visible = false;
                app.offset6.Visible = false;
                app.Tha6.Visible = false;
                app.Twist6.Visible = false;               
                            
            elseif(strcmp(app.DOFDropDown.Value,'3'))
            
                app.length_1.Visible = true;
                app.offset_1.Visible = true;
                app.Tha_1.Visible = true;
                app.Twist_1.Visible = true;
                    
                app.length_2.Visible = true;
                app.offset_2.Visible = true;
                app.Tha_2.Visible = true;
                app.Twist_2.Visible = true;
                
                app.length_3.Visible = true;
                app.offset_3.Visible = true;
                app.Tha_3.Visible = true;
                app.Twist_3.Visible = true;
            
                app.length_4.Visible = false;
                app.offset_4.Visible = false;
                app.Tha_4.Visible = false;
                app.Twist_4.Visible = false;            
                
                app.length_5.Visible = false;
                app.offset_5.Visible = false;
                app.Tha_5.Visible = false;
                app.Twist_5.Visible = false;   
            
                app.length_6.Visible = false;
                app.offset_6.Visible = false;
                app.Tha_6.Visible = false;
                app.Twist_6.Visible = false;
            
                %**************************************
                app.length1.Visible = true;
                app.offset.Visible = true;
                app.Tha1.Visible = true;
                app.Twist1.Visible = true;
                    
                app.length2.Visible = true;
                app.offset2.Visible = true;
                app.Tha2.Visible = true;
                app.Twist2.Visible = true;
                
                app.length3.Visible = true;
                app.offset3.Visible = true;
                app.Tha3.Visible = true;
                app.Twist3.Visible = true;
            
                app.length4.Visible = false;
                app.offset4.Visible = false;
                app.Tha4.Visible = false;
                app.Twist4.Visible = false;            
                
                app.length5.Visible = false;
                app.offset5.Visible = false;
                app.Tha5.Visible = false;
                app.Twist5.Visible = false;   
            
                app.length6.Visible = false;
                app.offset6.Visible = false;
                app.Tha6.Visible = false;
                app.Twist6.Visible = false;
                
            elseif(strcmp(app.DOFDropDown.Value,'4'))    
           
                app.length_1.Visible = true;
                app.offset_1.Visible = true;
                app.Tha_1.Visible = true;
                app.Twist_1.Visible = true;
        
                app.length_2.Visible = true;
                app.offset_2.Visible = true;
                app.Tha_2.Visible = true;
                app.Twist_2.Visible = true;
                
                app.length_3.Visible = true;
                app.offset_3.Visible = true;
                app.Tha_3.Visible = true;
                app.Twist_3.Visible = true;
                           
                app.length_4.Visible = true;
                app.offset_4.Visible = true;
                app.Tha_4.Visible = true;
                app.Twist_4.Visible = true;
            
                app.length_5.Visible = false;
                app.offset_5.Visible = false;
                app.Tha_5.Visible = false;
                app.Twist_5.Visible = false;   
            
                app.length_6.Visible = false;
                app.offset_6.Visible = false;
                app.Tha_6.Visible = false;
                app.Twist_6.Visible = false;
         
                %**************************************
                app.length1.Visible = true;
                app.offset.Visible = true;
                app.Tha1.Visible = true;
                app.Twist1.Visible = true;
        
                app.length2.Visible = true;
                app.offset2.Visible = true;
                app.Tha2.Visible = true;
                app.Twist2.Visible = true;
                
                app.length3.Visible = true;
                app.offset3.Visible = true;
                app.Tha3.Visible = true;
                app.Twist3.Visible = true;
                           
                app.length4.Visible = true;
                app.offset4.Visible = true;
                app.Tha4.Visible = true;
                app.Twist4.Visible = true;
            
                app.length5.Visible = false;
                app.offset5.Visible = false;
                app.Tha5.Visible = false;
                app.Twist5.Visible = false;   
            
                app.length6.Visible = false;
                app.offset6.Visible = false;
                app.Tha6.Visible = false;
                app.Twist6.Visible = false;

            elseif(strcmp(app.DOFDropDown.Value,'5'))    
            
                app.length_1.Visible = true;
                app.offset_1.Visible = true;
                app.Tha_1.Visible = true;
                app.Twist_1.Visible = true;
                    
                app.length_2.Visible = true;
                app.offset_2.Visible = true;
                app.Tha_2.Visible = true;
                app.Twist_2.Visible = true;
                
                app.length_3.Visible = true;
                app.offset_3.Visible = true;
                app.Tha_3.Visible = true;
                app.Twist_3.Visible = true;
                           
                app.length_4.Visible = true;
                app.offset_4.Visible = true;
                app.Tha_4.Visible = true;
                app.Twist_4.Visible = true;            
                
                app.length_5.Visible = true;
                app.offset_5.Visible = true;
                app.Tha_5.Visible = true;
                app.Twist_5.Visible = true;   
                
                app.length_6.Visible = false;
                app.offset_6.Visible = false;
                app.Tha_6.Visible = false;
                app.Twist_6.Visible = false;
                
   
                %**************************************
                app.length1.Visible = true;
                app.offset.Visible = true;
                app.Tha1.Visible = true;
                app.Twist1.Visible = true;
                    
                app.length2.Visible = true;
                app.offset2.Visible = true;
                app.Tha2.Visible = true;
                app.Twist2.Visible = true;
                
                app.length3.Visible = true;
                app.offset3.Visible = true;
                app.Tha3.Visible = true;
                app.Twist3.Visible = true;
                           
                app.length4.Visible = true;
                app.offset4.Visible = true;
                app.Tha4.Visible = true;
                app.Twist4.Visible = true;            
                
                app.length5.Visible = true;
                app.offset5.Visible = true;
                app.Tha5.Visible = true;
                app.Twist5.Visible = true;   
                
                app.length6.Visible = false;
                app.offset6.Visible = false;
                app.Tha6.Visible = false;
                app.Twist6.Visible = false;

            elseif(strcmp(app.DOFDropDown.Value,'6'))    
                    
                app.length_1.Visible = true;
                app.offset_1.Visible = true;
                app.Tha_1.Visible = true;
                app.Twist_1.Visible = true;
                    
                app.length_2.Visible = true;
                app.offset_2.Visible = true;
                app.Tha_2.Visible = true;
                app.Twist_2.Visible = true;
                
                app.length_3.Visible = true;
                app.offset_3.Visible = true;
                app.Tha_3.Visible = true;
                app.Twist_3.Visible = true;
                           
                app.length_4.Visible = true;
                app.offset_4.Visible = true;
                app.Tha_4.Visible = true;
                app.Twist_4.Visible = true;            
                
                app.length_5.Visible = true;
                app.offset_5.Visible = true;
                app.Tha_5.Visible = true;
                app.Twist_5.Visible = true;   
            
                app.length_6.Visible = true;
                app.offset_6.Visible = true;
                app.Tha_6.Visible = true;
                app.Twist_6.Visible = true;   
                  
                %**************************************                    
                app.length1.Visible = true;
                app.offset.Visible = true;
                app.Tha1.Visible = true;
                app.Twist1.Visible = true;
                    
                app.length2.Visible = true;
                app.offset2.Visible = true;
                app.Tha2.Visible = true;
                app.Twist2.Visible = true;
                
                app.length3.Visible = true;
                app.offset3.Visible = true;
                app.Tha3.Visible = true;
                app.Twist3.Visible = true;
                           
                app.length4.Visible = true;
                app.offset4.Visible = true;
                app.Tha4.Visible = true;
                app.Twist4.Visible = true;            
                
                app.length5.Visible = true;
                app.offset5.Visible = true;
                app.Tha5.Visible = true;
                app.Twist5.Visible = true;   
            
                app.length6.Visible = true;
                app.offset6.Visible = true;
                app.Tha6.Visible = true;
                app.Twist6.Visible = true;  
                
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed2(app, event)
            
            if (strcmp(app.SelectModeDropDown.Value,'Forward'))
                
                alpha = sym('alpha');
                theta = sym('theta');
                a = sym('a');
                d = sym('d');
                
                app.X.Enable = true;
                app.Y.Enable = true;
                app.Z.Enable = true;
                
                app.peter_x.Enable = true;
                app.peter_y.Enable = true;
                app.peter_z.Enable = true;
                
                a1 = app.Link1length.Value;
                a2 = app.Link2length.Value;
                
                alpha1 = (app.Alpha1.Value)*pi/180;
                alpha2 = (app.Alpha2.Value)*pi/180;
                
                d1 = app.Link1offset.Value;
                d2 = app.Link2offset.Value;
                
                theta1 = (app.Theta1.Value)*pi/180;
                theta2 = (app.Theta2.Value)*pi/180;
                
                N = 2;
                DhTable = [a1,alpha1,d1,theta1;
                           a2,alpha2,d2,theta2;];
                
                A = cell(1,N);
                
                for i = 1:N
                    a = DhTable(i,1);
                    alpha = DhTable(i,2);
                    d = DhTable(i,3);
                    theta = DhTable(i,4);
                    A{i} = [ cos(theta)          -sin(theta)*cos(alpha)      sin(theta)*sin(alpha)         a*cos(theta);
                             sin(theta)           cos(theta)*cos(alpha)     -cos(theta)*sin(alpha)         a*sin(theta);
                                 0                    sin(alpha)                 cos(alpha)                    d;
                                 0                    0                          0                             1;];      
                end

                T = eye(4);
                
                for i=1:N
                    T = T*A{i};
                    
                end
                
            x = T(1, 4);
            y = T(2, 4);
            z = T(3, 4);
            app.X.Value = x;
            app.Y.Value = y;
            app.Z.Value = z;
                                     
            L(1) = Link([theta1 d1 a1  alpha1]);
            L(2) = Link([theta2 d2 a2  alpha1]);
            q = [theta1 theta2 ] ;
            
            Robot = SerialLink(L);
            Robot.name = 'Robot';
            Robot.plot(q);
            T = Robot.fkine(q);
            % T is SE3 matrix have t is transformation matrix have X,Y,Z 
            a = T.t;
            app.peter_x.Value = a(1,1);
            app.peter_y.Value = a(2,1);
            app.peter_z.Value = a(3,1);
            
            elseif (strcmp(app.SelectModeDropDown.Value,'Inverse'))
                
                % Using Numirical method to determine the point for 2 link
                a1 = app.Link1length.Value;
                a2 = app.Link2length.Value;    
                x = app.X.Value;
                y = app.Y.Value;
                
                % Check if the Point is larger than sum of two links or
                % the Point is smaller than the absolute the subtraction
                % between the two links then this point will not be
                % reachable
                if((a1+a2)<sqrt((x*x)+(y*y)) ||  (sqrt((x*x)+(y*y)) < (abs(a1-a2))) )
                 app.text.Enable = true;
                 app.text.Visible = true; 
                 app.text.Value = "Point can't be reached" ;
                 
                elseif((a1+a2)>=sqrt((x*x)+(y*y))  && (sqrt((x*x)+(y*y)) > (abs(a1-a2)))  )
               
                app.nd_Solution_Th1.Visible = true;
                app.nd_Solution_Th2.Visible = true;
                app.nd_Solution_Th_1.Visible = true;
                app.nd_Solution_Th_2.Visible = true;
                
                app.text.Enable = false;
                app.text.Visible = false; 
                app.peter_Tha1.Enable = true;
                app.peter_Tha2.Enable = true;
                app.Theta1.Enable = true;
                app.Theta2.Enable = true;
                
                % Length of the two links a1,a2
                a1 = app.Link1length.Value;
                a2 = app.Link2length.Value;    
                x = app.X.Value;
                y = app.Y.Value;
                
                % q is the initial guess it depend on the quadrant that
                % point relay on
                if (x>=0 && y>=0)
                   q = [pi/3;pi/3];
                elseif (x<0 && y>0)
                   q = [-pi/3;pi/3];
                elseif (x>0 && y<0)
                   q = [pi/3;-pi/3];
                elseif (x<0 && y<0)
                   q = [-pi/3;-pi/3];
                end
                
                % Mu_a is the point that user want to reach
                Mu_a = [x;y;];
                for i = 1:100
                    first_theta = q(1);
                    second_theta = q(2);
                    % Calculate jacopina matrix
                    J = [-a2*sin(first_theta + second_theta)-a1*sin(first_theta),-a2*sin(first_theta + second_theta);a2*cos(first_theta + second_theta)+a1*cos(first_theta),a2*cos(first_theta + second_theta);];    
                    % Mu_e is  calculate the point at the q in the first
                    % itration q is the initial guess 
                    Mu_e = [a1*cos(first_theta)+ a2*cos(first_theta + second_theta);a1*sin(first_theta)+a2*sin(first_theta + second_theta);];
                    % delta is the error
                    delta = Mu_a - Mu_e;
                    % 1e-5 margin error
                    if abs(delta)<1e-5
                        break;
                    end
                    q = q + inv(J)*(delta); 
                    
                    plot([0 a1*cos(first_theta) Mu_e(1)],[0 a1*sin(first_theta) Mu_e(2)],'b-o','markersize',10)
                    hold on 
                    plot( Mu_a(1), Mu_a(2),'r*','markersize',10)
                    plot( 0, 0,'ks','markersize',10)
                    axis([-(a1+a2) (a1+a2) -(a1+a2) (a1+a2)])
                    grid on
                    pause(1)
                                
                end
                
                    if (q(1) > 0)
                        while (q(1) > 2*pi)
                            q(1) = q(1) - (2*pi);
                        end
                    elseif (q(1) < 0)
                        while (q(1) < (-2*pi))
                            q(1) = q(1) + (2*pi);
                        end
                    end

                    if (q(2) > 0)
                        while (q(2) > 2*pi)
                            q(2) = q(2) - (2*pi);
                        end
                    elseif (q(2) < 0 )
                        while (q(2) < (-2*pi))
                            q(2) = q(2) + (2*pi);
                        end
                    end
                    
                    if (q(2)>pi)
                       q(2) = q(2) - (2*pi);
                      
                    end
                    if (q(2)<-pi)
                       q(2) = q(2) + (2*pi);
                      
                    end
                    
                    app.Theta2.Value = q(2)*180/pi;
                    app.Theta1.Value = q(1)*180/pi;

                    L(1) = Link([first_theta 0 a1  0]);
                    L(2) = Link([second_theta 0 a2  0]);
                    
                    % confirming answer by Peter Corke toolbox
                    kr = SerialLink(L);
                    qf = [first_theta second_theta];
                    tf = [1 0 0 x;
                          0 1 0 y;
                          0 0 1 0;
                          0 0 0 1;];
                    q0 = [0 0];
                    n = kr.ikine(tf,q0,'mask',[1 1 0 0 0 0]);
                    app.peter_Tha1.Value = n(1)*180/pi;
                    app.peter_Tha2.Value = n(2)*180/pi;

                    % Calculate second solution and plot it 
                    if (q(1) < 0)
                        q(1) = q(1) + 2*pi ;
                    end
                    
                    gama = acos(((a2*a2)-(a1*a1)-((x*x)+(y*y)))/(-2*a1*sqrt((x*x)+(y*y))));
                    
                    if (q(2) > 0)
                        nd_solution_theta1 = q(1) + 2*gama;
                    elseif (q(2) < 0)
                        nd_solution_theta1 = q(1) - 2*gama;
                    end
                    nd_solution_theta2 = -1 * q(2) ;
                    
                    if (nd_solution_theta1 > 0)
                        while (nd_solution_theta1 > 2*pi)
                            nd_solution_theta1 = nd_solution_theta1 - (2*pi);
                        end
                    elseif (nd_solution_theta1 < 0)
                        while (nd_solution_theta1 < (-2*pi))
                            nd_solution_theta1 = nd_solution_theta1 + (2*pi);
                        end
                    end

                    if (nd_solution_theta2 > 0)
                        while (nd_solution_theta2 > 2*pi)
                            nd_solution_theta2 = nd_solution_theta2 - (2*pi);
                        end
                    elseif (nd_solution_theta2 < 0 )
                        while (nd_solution_theta2 < (-2*pi))
                            nd_solution_theta2 = nd_solution_theta2 + (2*pi);
                        end
                    end
                    
                    app.nd_Solution_Th1.Value = nd_solution_theta1 * 180/pi ;
                    app.nd_Solution_Th2.Value = nd_solution_theta2 * 180/pi ;

                    first_theta = nd_solution_theta1;
                    second_theta = nd_solution_theta2;
                    
                    Mu_e = [a1*cos(first_theta)+ a2*cos(first_theta + second_theta);a1*sin(first_theta)+a2*sin(first_theta + second_theta);];
                    
                    plot([0 a1*cos(first_theta) Mu_e(1)],[0 a1*sin(first_theta) Mu_e(2)],'r-o','markersize',10)
                    hold on 
                    plot( Mu_a(1), Mu_a(2),'r*','markersize',10)
                    plot( 0, 0,'ks','markersize',10)
                    axis([-(a1+a2) (a1+a2) -(a1+a2) (a1+a2)])
                    grid on
                    pause(1)

                end
                    

            elseif (strcmp(app.SelectModeDropDown.Value,'Workspace'))
   
                app.peter_Tha1.Enable = false;
                app.peter_Tha2.Enable = false;  
                
            if (strcmp(app.WorkModeDropDown.Value,'Links'))
                
                % Theta is the limits of the robot that can rotate 
                th1 = app.Theta1.Value ;
                th2 = app.Theta2.Value ;       
                plot(0,0,'b')   
                hold on 
                
                %Length of the Links.
                l1 = app.Link1length.Value;
                l2 = app.Link2length.Value;
                %Angles with the x-axis.
                theta1 = linspace (0,th1,30);
                theta2 = linspace (0,th2,30);
                ct =1;
                
                for i = 1:length(theta1)
                    
                    A= theta1(i);
                    for j = 1:length(theta2) 
                        
                        B= theta2(j);
                        %The coordinates of the Base of the first link.
                        x0 = 0; 
                        y0 = 0;
                        %The coordinates of the end point of the first link. 
                        x1= l1 * cosd(A);
                        y1 = l1 * sind(A); 
                        %The coordinates of the manipulator.
                        x2 = x1 + l2*cosd(B);
                        y2 = y1 + l2*sind(B);
                       
                        %Animation Plot
                        figure(1)
                        plot([x0 x1],[y0 y1],[x1 x2],[y1 y2],'linewidth', 2)
                        axis([-15 15 -15 15])
                        grid on
                        M(ct) = getframe(gcf);
                        ct = ct + 1; 
                    end 
                end 
                
            elseif(strcmp(app.WorkModeDropDown.Value,'2D'))
               % This code need function to download it and set bath at Home of MATLAB
               % You can see the code from this link
               % https://github.com/NiklasZ/robotic_arm_workspace/tree/main
                syms theta1 theta2 theta3 real
                syms d1 d2 real
                a1 = app.Link1length.Value;
                a2 = app.Link2length.Value; 
                app.Theta1.Enable = true;
                app.Theta1.Enable = true;
                th1 = app.Theta1.Value * pi/180;
                th2 = app.Theta2.Value * pi/180;
     
                % DH parameters
                % a1,a2 the length of links that you plot 
                test_dh = [0 0 0 theta1; ...
                           a1 0 0 theta2; ...
                           a2 0 0 0];
               
                % Parameter ranges
                % th1,th2 is the limits of the robot that can rotate                       
                theta1_range = linspace(0,th1, 180);
                theta2_range = linspace(0,th2, 180);
                test_map = containers.Map({'theta1', 'theta2'},{theta1_range,theta2_range});
                
                % Workspace plotting function
                plot2dworkspace(test_dh, test_map)
            end
      end
        end

        % Value changed function: SelectModeDropDown
        function SelectModeDropDownValueChanged2(app, event)
           
            if (strcmp(app.SelectModeDropDown.Value,'Forward'))
              
                app.WorkModeDropDown.Enable = false; 
                
                app.Theta1.Enable = true;
                app.Theta2.Enable = true;
                app.Link1offset.Enable = true;
                app.Link2offset.Enable = true;
                
                app.Alpha1.Enable = true;
                app.Alpha2.Enable = true;
                app.X.Enable = false;
                app.Y.Enable = false;
                app.Z.Enable = false;
                
                app.Theta1.Value = 0;
                app.Theta2.Value = 0;
       
                app.peter_Tha1.Visible = false;
                app.peter_Tha2.Visible = false;
                app.peter_Tha_1.Visible = false;
                app.peter_Tha_2.Visible = false;
                
                app.peter_x.Visible = true;
                app.peter_y.Visible = true;
                app.peter_z.Visible = true;
                
                app.peterx.Visible = true;
                app.petery.Visible = true;
                app.peterz.Visible = true;
                
                app.nd_Solution_Th_1.Visible = false;
                app.nd_Solution_Th_2.Visible = false;
                app.nd_Solution_Th1.Visible = false;
                app.nd_Solution_Th2.Visible = false;
                
            elseif (strcmp(app.SelectModeDropDown.Value,'Inverse'))
                
                app.WorkModeDropDown.Enable = false; 
                app.X.Enable = true;
                app.Y.Enable = true;
                app.Z.Enable = true;
                
                app.Link1offset.Enable = false;
                app.Link2offset.Enable = false;
                                
                app.Theta1.Enable = false;
                app.Theta2.Enable = false;
                            
                app.X.Value = 0;
                app.Y.Value = 0;
                app.Z.Value = 0;
                
                app.Alpha1.Enable = false;
                app.Alpha2.Enable = false;
                app.Z.Enable = false;
               
                app.peter_x.Visible = false;
                app.peter_y.Visible = false;
                app.peter_z.Visible = false;
                app.peterx.Visible = false;
                app.petery.Visible = false;
                app.peterz.Visible = false;
          
                app.peter_Tha1.Visible = true;
                app.peter_Tha2.Visible = true;
                app.peter_Tha_1.Visible = true;
                app.peter_Tha_2.Visible = true;
                
                app.nd_Solution_Th_1.Visible = false;
                app.nd_Solution_Th_2.Visible = false;
             
            elseif (strcmp(app.SelectModeDropDown.Value,'Workspace'))
                app.WorkModeDropDown.Enable = true; 
                
                app.Theta1.Enable = true;
                app.Theta2.Enable = true;
                app.EnterYourLinkLimitsLabel.Visible = true ;
             
                app.Alpha1.Enable = false;
                app.Alpha2.Enable = false;
                
                app.Link1offset.Enable = false;
                app.Link2offset.Enable = false;

                app.X.Enable = false;
                app.Y.Enable = false;
                app.Z.Enable = false;  
                
                app.Theta1.Enable = false;
                app.Theta2.Enable = false;
                
                app.peter_Tha1.Enable = false;
                app.peter_Tha2.Enable = false;
                
                app.peter_x.Visible = false;
                app.peter_y.Visible = false;
                app.peter_z.Visible = false;
                
                app.peterx.Visible = false;
                app.petery.Visible = false;
                app.peterz.Visible = false;
                
                app.peter_Tha1.Visible = false;
                app.peter_Tha2.Visible = false;
                
                app.peter_Tha_1.Visible = false;
                app.peter_Tha_2.Visible = false;

                app.nd_Solution_Th_1.Visible = false;
                app.nd_Solution_Th_2.Visible = false;
                app.nd_Solution_Th1.Visible = false;
                app.nd_Solution_Th2.Visible = false;
     
            end 
        end

        % Value changed function: WorkModeDropDown
        function WorkModeDropDownValueChanged(app, event)
                app.Theta1.Enable = true;
                app.Theta2.Enable = true;
                app.EnterYourLinkLimitsLabel.Visible = true ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ForwardTab = uitab(app.TabGroup);
            app.ForwardTab.Title = 'Forward';
            app.length_2 = uieditfield(app.ForwardTab, 'numeric');
            app.length_2.Visible = 'off';
            app.Tha_2 = uieditfield(app.ForwardTab, 'numeric');
            app.Tha_2.Visible = 'off';
            app.offset_2 = uieditfield(app.ForwardTab, 'numeric');
            app.offset_2.Visible = 'off';
            app.Twist_2 = uieditfield(app.ForwardTab, 'numeric');
            app.Twist_2.Visible = 'off';
            app.length_3 = uieditfield(app.ForwardTab, 'numeric');
            app.length_3.Visible = 'off';
            app.Tha_3 = uieditfield(app.ForwardTab, 'numeric');
            app.Tha_3.Visible = 'off';
            app.offset_3 = uieditfield(app.ForwardTab, 'numeric');
            app.offset_3.Visible = 'off';
            app.Twist_3 = uieditfield(app.ForwardTab, 'numeric');
            app.Twist_3.Visible = 'off';
            app.length_4 = uieditfield(app.ForwardTab, 'numeric');
            app.length_4.Visible = 'off';
            app.Tha_4 = uieditfield(app.ForwardTab, 'numeric');
            app.Tha_4.Visible = 'off';
            app.offset_4 = uieditfield(app.ForwardTab, 'numeric');
            app.offset_4.Visible = 'off';
            app.Twist_4 = uieditfield(app.ForwardTab, 'numeric');
            app.Twist_4.Visible = 'off';
            app.length_5 = uieditfield(app.ForwardTab, 'numeric');
            app.length_5.Visible = 'off';
            app.Tha_5 = uieditfield(app.ForwardTab, 'numeric');
            app.Tha_5.Visible = 'off';
            app.offset_5 = uieditfield(app.ForwardTab, 'numeric');
            app.offset_5.Visible = 'off';
            app.Twist_5 = uieditfield(app.ForwardTab, 'numeric');
            app.Twist_5.Visible = 'off';
            app.length_6 = uieditfield(app.ForwardTab, 'numeric');
            app.length_6.Visible = 'off';
            app.Tha_6 = uieditfield(app.ForwardTab, 'numeric');
            app.Tha_6.Visible = 'off';
            app.offset_6 = uieditfield(app.ForwardTab, 'numeric');
            app.offset_6.Visible = 'off';
            app.Twist_6 = uieditfield(app.ForwardTab, 'numeric');
            app.Twist_6.Visible = 'off';
            app.DOFDropDownLabel = uilabel(app.ForwardTab);
            app.DOFDropDownLabel.Text = 'DOF';
            app.DOFDropDown = uidropdown(app.ForwardTab);
            app.DOFDropDown.Items = {'2', '3', '4', '5', '6', '--------------'};
            app.DOFDropDown.ValueChangedFcn = createCallbackFcn(app, @DOFDropDownValueChanged, true);
            app.DOFDropDown.Value = '--------------';
            app.ForwardButton = uibutton(app.ForwardTab, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.length_1 = uieditfield(app.ForwardTab, 'numeric');
            app.length_1.Visible = 'off';
            app.Tha_1 = uieditfield(app.ForwardTab, 'numeric');
            app.Tha_1.Visible = 'off';
            app.offset_1 = uieditfield(app.ForwardTab, 'numeric');
            app.offset_1.Visible = 'off';
            app.Twist_1 = uieditfield(app.ForwardTab, 'numeric');
            app.Twist_1.Visible = 'off';
            app.XEditFieldLabel_2 = uilabel(app.ForwardTab);
            app.XEditFieldLabel_2.Text = 'X';
            app.X_2 = uieditfield(app.ForwardTab, 'numeric');
            app.X_2.Enable = 'off';
            app.YEditFieldLabel_2 = uilabel(app.ForwardTab);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Y_2 = uieditfield(app.ForwardTab, 'numeric');
            app.Y_2.Enable = 'off';
            app.ZEditFieldLabel_2 = uilabel(app.ForwardTab);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Z_2 = uieditfield(app.ForwardTab, 'numeric');
            app.Z_2.Enable = 'off';
            app.length1 = uilabel(app.ForwardTab);
            app.length1.Visible = 'off';
            app.length1.Text = ' length 1';
            app.length2 = uilabel(app.ForwardTab);
            app.length2.Visible = 'off';
            app.length2.Text = ' length 2';
            app.length3 = uilabel(app.ForwardTab);
            app.length3.Visible = 'off';
            app.length3.Text = ' length 3';
            app.length4 = uilabel(app.ForwardTab);
            app.length4.Visible = 'off';
            app.length4.Text = ' length 4';
            app.length5 = uilabel(app.ForwardTab);
            app.length5.Visible = 'off';
            app.length5.Text = ' length 5';
            app.length6 = uilabel(app.ForwardTab);
            app.length6.Visible = 'off';
            app.length6.Text = ' length 6';
            app.Tha1 = uilabel(app.ForwardTab);
            app.Tha1.Visible = 'off';
            app.Tha1.Text = 'Tha 1';
            app.Tha2 = uilabel(app.ForwardTab);
            app.Tha2.Visible = 'off';
            app.Tha2.Text = ' Tha 2';
            app.Tha3 = uilabel(app.ForwardTab);
            app.Tha3.Visible = 'off';
            app.Tha3.Text = 'Tha 3';
            app.Tha4 = uilabel(app.ForwardTab);
            app.Tha4.Visible = 'off';
            app.Tha4.Text = 'Tha 4';
            app.Tha5 = uilabel(app.ForwardTab);
            app.Tha5.Visible = 'off';
            app.Tha5.Text = 'Tha 5';
            app.Tha6 = uilabel(app.ForwardTab);
            app.Tha6.Visible = 'off';
            app.Tha6.Text = 'Tha 6';
            app.offset = uilabel(app.ForwardTab);
            app.offset.Visible = 'off';
            app.offset.Text = 'offeset 1';
            app.offset2 = uilabel(app.ForwardTab);
            app.offset2.Visible = 'off';
            app.offset2.Text = 'offeset 2';
            app.offset3 = uilabel(app.ForwardTab);
            app.offset3.Visible = 'off';
            app.offset3.Text = 'offeset 3';
            app.offset4 = uilabel(app.ForwardTab);
            app.offset4.Visible = 'off';
            app.offset4.Text = 'offeset 4';
            app.offset5 = uilabel(app.ForwardTab);
            app.offset5.Visible = 'off';
            app.offset5.Text = 'offeset 5';
            app.offset6 = uilabel(app.ForwardTab);
            app.offset6.Visible = 'off';
            app.offset6.Text = 'offeset 6';
            app.Twist1 = uilabel(app.ForwardTab);
            app.Twist1.Visible = 'off';
            app.Twist1.Text = 'Twist 1';
            app.Twist2 = uilabel(app.ForwardTab);
            app.Twist2.Visible = 'off';
            app.Twist2.Text = 'Twist 2';
            app.Twist3 = uilabel(app.ForwardTab);
            app.Twist3.Visible = 'off';
            app.Twist3.Text = 'Twist 3';
            app.Twist4 = uilabel(app.ForwardTab);
            app.Twist4.Visible = 'off';
            app.Twist4.Text = 'Twist 4';
            app.Twist5 = uilabel(app.ForwardTab);
            app.Twist5.Visible = 'off';
            app.Twist5.Text = 'Twist 5';
            app.Twist6 = uilabel(app.ForwardTab);
            app.Twist6.Visible = 'off';
            app.Twist6.Text = 'Twist 6';
            app.TwoLinkTab = uitab(app.TabGroup);
            app.TwoLinkTab.Title = 'Two Link';
            app.text = uieditfield(app.TwoLinkTab, 'text');
            app.text.Enable = 'off';
            app.text.Visible = 'off';
            app.SubmitButton = uibutton(app.TwoLinkTab, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed2, true);
            app.SubmitButton.Text = 'Submit';
            app.Link1lengthEditFieldLabel = uilabel(app.TwoLinkTab);
            app.Link1lengthEditFieldLabel.Text = 'Link 1 length';
            app.Link1length = uieditfield(app.TwoLinkTab, 'numeric');
            app.Link2lengthEditFieldLabel = uilabel(app.TwoLinkTab);
            app.Link2lengthEditFieldLabel.Text = 'Link 2 length';
            app.Link2length = uieditfield(app.TwoLinkTab, 'numeric');
            app.SelectModeDropDownLabel = uilabel(app.TwoLinkTab);
            app.SelectModeDropDownLabel.Text = 'Select Mode ';
            app.SelectModeDropDown = uidropdown(app.TwoLinkTab);
            app.SelectModeDropDown.Items = {'Forward', 'Inverse', 'Workspace', '-------------'};
            app.SelectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectModeDropDownValueChanged2, true);
            app.SelectModeDropDown.Value = '-------------';
            app.Theta1EditFieldLabel = uilabel(app.TwoLinkTab);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1 = uieditfield(app.TwoLinkTab, 'numeric');
            app.Theta1.Enable = 'off';
            app.Theta2EditFieldLabel = uilabel(app.TwoLinkTab);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2 = uieditfield(app.TwoLinkTab, 'numeric');
            app.Theta2.Enable = 'off';
            app.Alpha1EditFieldLabel = uilabel(app.TwoLinkTab);
            app.Alpha1EditFieldLabel.Text = 'Alpha 1';
            app.Alpha1 = uieditfield(app.TwoLinkTab, 'numeric');
            app.Alpha2EditFieldLabel = uilabel(app.TwoLinkTab);
            app.Alpha2EditFieldLabel.Text = 'Alpha 2';
            app.Alpha2 = uieditfield(app.TwoLinkTab, 'numeric');
            app.XEditFieldLabel_3 = uilabel(app.TwoLinkTab);
            app.XEditFieldLabel_3.Text = 'X';
            app.X = uieditfield(app.TwoLinkTab, 'numeric');
            app.X.Enable = 'off';
            app.YEditFieldLabel_3 = uilabel(app.TwoLinkTab);
            app.YEditFieldLabel_3.Text = 'Y';
            app.Y = uieditfield(app.TwoLinkTab, 'numeric');
            app.Y.Enable = 'off';
            app.ZEditFieldLabel_3 = uilabel(app.TwoLinkTab);
            app.ZEditFieldLabel_3.Text = 'Z';
            app.Z = uieditfield(app.TwoLinkTab, 'numeric');
            app.Z.Enable = 'off';
            app.peter_z = uieditfield(app.TwoLinkTab, 'numeric');
            app.peter_z.Enable = 'off';
            app.peter_y = uieditfield(app.TwoLinkTab, 'numeric');
            app.peter_y.Enable = 'off';
            app.peter_x = uieditfield(app.TwoLinkTab, 'numeric');
            app.peter_x.Enable = 'off';
            app.peter_Tha2 = uieditfield(app.TwoLinkTab, 'numeric');
            app.peter_Tha2.Enable = 'off';
            app.peter_Tha1 = uieditfield(app.TwoLinkTab, 'numeric');
            app.peter_Tha1.Enable = 'off';
            app.Link2offsetLabel = uilabel(app.TwoLinkTab);
            app.Link2offsetLabel.Text = 'Link 2 offset';
            app.Link1offset = uieditfield(app.TwoLinkTab, 'numeric');
            app.Link1offsetLabel = uilabel(app.TwoLinkTab);
            app.Link1offsetLabel.Text = 'Link 1 offset';
            app.Link2offset = uieditfield(app.TwoLinkTab, 'numeric');
            app.WorkModeDropDownLabel = uilabel(app.TwoLinkTab);
            app.WorkModeDropDownLabel.Text = 'Work Mode';
            app.WorkModeDropDown = uidropdown(app.TwoLinkTab);
            app.WorkModeDropDown.Items = {'2D', 'Links', '-------------'};
            app.WorkModeDropDown.ValueChangedFcn = createCallbackFcn(app, @WorkModeDropDownValueChanged, true);
            app.WorkModeDropDown.Enable = 'off';
            app.WorkModeDropDown.Value = '-------------';
            app.nd_Solution_Th2 = uieditfield(app.TwoLinkTab, 'numeric');
            app.nd_Solution_Th2.Visible = 'off';
            app.nd_Solution_Th1 = uieditfield(app.TwoLinkTab, 'numeric');
            app.nd_Solution_Th1.Visible = 'off';
            app.nd_Solution_Th_1 = uilabel(app.TwoLinkTab);
            app.nd_Solution_Th_1.Visible = 'off';
            app.nd_Solution_Th_1.Text = 'nd Solution Th 1';
            app.nd_Solution_Th_2 = uilabel(app.TwoLinkTab);
            app.nd_Solution_Th_2.Visible = 'off';
            app.nd_Solution_Th_2.Text = 'nd Solution Th 2';
            app.peter_Tha_1 = uilabel(app.TwoLinkTab);
            app.peter_Tha_1.Text = 'Peter corke Th1 ';
            app.peter_Tha_2 = uilabel(app.TwoLinkTab);
            app.peter_Tha_2.Text = 'Peter corke Th2 ';
            app.peterx = uilabel(app.TwoLinkTab);
            app.peterx.Text = 'Peter corke X';
            app.petery = uilabel(app.TwoLinkTab);
            app.petery.Text = 'Peter corke Y';
            app.peterz = uilabel(app.TwoLinkTab);
            app.peterz.Text = 'Peter corke Z';
            app.EnterYourLinkLimitsLabel = uilabel(app.TwoLinkTab);
            app.EnterYourLinkLimitsLabel.Visible = 'off';
            app.EnterYourLinkLimitsLabel.Text = 'Enter Your Link Limits';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end